classdef TESTAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RoMMeasurementTab               matlab.ui.container.Tab
        CalibratingLabel                matlab.ui.control.Label
        NOLabel_2                       matlab.ui.control.Label
        IncorrectFormDetectedEditField  matlab.ui.control.EditField
        IncorrectFormDetectedEditFieldLabel  matlab.ui.control.Label
        NOLabel                         matlab.ui.control.Label
        OvercompensationDetectedEditField  matlab.ui.control.EditField
        OvercompensationDetectedLabel   matlab.ui.control.Label
        max_angle                       matlab.ui.control.Label
        MaxAngleEditField               matlab.ui.control.EditField
        MaxAngleEditFieldLabel          matlab.ui.control.Label
        curr_angle                      matlab.ui.control.Label
        CurrentAngleEditField           matlab.ui.control.EditField
        CurrentAngleEditFieldLabel      matlab.ui.control.Label
        StartExerciseButton             matlab.ui.control.Button
        resetmeasurementButton          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SelectanExerciseDropDown        matlab.ui.control.DropDown
        SubRightAxes                    matlab.ui.control.UIAxes
        SubLeftAxes                     matlab.ui.control.UIAxes
        MainAxes                        matlab.ui.control.UIAxes
        ViewPreviousExercisesTab        matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        ExternalRotationListBox         matlab.ui.control.ListBox
        ExternalRotationListBoxLabel    matlab.ui.control.Label
        FlexionListBox                  matlab.ui.control.ListBox
        FlexionListBoxLabel             matlab.ui.control.Label
        AbductionListBox                matlab.ui.control.ListBox
        AbductionListBoxLabel           matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        HelpTab                         matlab.ui.container.Tab
    end


    properties (Access = public)
        arduinoObj % Description
        MainPlot
        SubPlotLeft
        SubPlotRight
        MeasureInProg = 0 %Measurement in progress
        Exercise1; %Individual Exercises Storage
        Exercise2; 
        Exercise3;
        Exercise4;
        Exercise5;
        Exercise6;
        Exercise7; 
        Exercise8;
        Exercise9;
        Exercise10;
    end

    methods (Access = public)

        function cleardata(app)
            app.arduinoObj.UserData.RollPlot = plot(app.MainAxes,NaN,NaN); %TODO: ensure this doesnt change plotting locations
            app.arduinoObj.UserData.PitchPlot = plot(app.SubLeftAxes,NaN,NaN);
            app.arduinoObj.UserData.YawPlot = plot(app.SubRightAxes,NaN,NaN);
            app.curr_angle.Text = string(0);
            app.max_angle.Text = string(0);
            app.arduinoObj.UserData.Roll(:) = 0;
            app.arduinoObj.UserData.Pitch(:) = 0;
            app.arduinoObj.UserData.Yaw(:) = 0;
        end

        function readIMUData(app,src, ~)
            if(~app.MeasureInProg) 
                configureCallback(src, "off");
                return;
            end

            % Read the ASCII data from the serialport object.
            line = readline(src);

            %extract sensor data
            IMUdata = sscanf(line, '%f,%f,%f,%f');

            if (~isempty(IMUdata))

                if (IMUdata(1)==0)
                    Roll = IMUdata(2);
                    Pitch = IMUdata(3);
                    Yaw = IMUdata(4);

                    %solution for -180 to 180 discontinuity
                    %for efficiency, can check for normal case (current angle and previous one have same sign)
                    %first to skip the other comparisons
                    if (src.UserData.Count1 > 1)
                        if (abs(Roll) > 45 && sign(Roll * src.UserData.Roll(src.UserData.Count1-1)) < 0)
                            Roll = Roll + sign(src.UserData.Roll(src.UserData.Count1-1))*360;
                        end
                        if (abs(Pitch) > 45 && sign(Pitch * src.UserData.Pitch(src.UserData.Count1-1)) < 0)
                            Pitch = Pitch + sign(src.UserData.Pitch(src.UserData.Count1-1))*360;
                        end
                        if (abs(Yaw) > 45 && sign(Yaw * src.UserData.Yaw(src.UserData.Count1-1)) < 0)
                            Yaw = Yaw + sign(src.UserData.Yaw(src.UserData.Count1-1))*360;
                        end
                    end

                    src.UserData.Roll(src.UserData.Count1) = Roll;
                    src.UserData.Pitch(src.UserData.Count1) = Pitch;
                    src.UserData.Yaw(src.UserData.Count1) = Yaw;

                    src.UserData.Count1 = src.UserData.Count1 + 1;

                    elapsedTime = toc;

                    %update plot data by adding on the new points
                    xdata = get(src.UserData.RollPlot,'XData');
                    ydata = get(src.UserData.RollPlot,'YData');
                    set(src.UserData.RollPlot,'XData',[xdata elapsedTime], 'YData',[ydata Roll],"Color","blue");

                    app.curr_angle.Text = string(Roll);
                    app.max_angle.Text = string(max(src.UserData.Roll));

                    xdata2 = get(src.UserData.PitchPlot,'XData');
                    ydata2 = get(src.UserData.PitchPlot,'YData');
                    set(src.UserData.PitchPlot,'XData',[xdata2 elapsedTime], 'YData',[ydata2 Pitch],"Color","blue");

                    xdata3 = get(src.UserData.YawPlot,'XData');
                    ydata3 = get(src.UserData.YawPlot,'YData');
                    set(src.UserData.YawPlot,'XData',[xdata3 elapsedTime], 'YData',[ydata3 Yaw],"Color","blue");

                elseif (IMUdata(1)==1)

%                    Roll2 = IMUdata(2);
%                     Pitch2 = IMUdata(3);
%                     Yaw2 = IMUdata(4);
% 
%                     if (abs(Roll2) > 45 && sign(Roll2 * src.UserData.Roll2(src.UserData.Count2-1)) < 0)
%                         Roll2 = Roll2 + sign(src.UserData.Roll2(src.UserData.Count2-1))*360;
%                     end
%                     if (abs(Pitch2) > 45 && sign(Pitch2 * src.UserData.Pitch2(src.UserData.Count2-1)) < 0)
%                         Pitch2 = Pitch2 + sign(src.UserData.Pitch2(src.UserData.Count2-1))*360;
%                     end
%                     if (abs(Yaw2) > 45 && sign(Yaw2 * src.UserData.Yaw2(src.UserData.Count2-1)) < 0)
%                         Yaw2 = Yaw2 + sign(src.UserData.Yaw2(src.UserData.Count2-1))*360;
%                     end
% 
%                     src.UserData.Roll2(src.UserData.Count2) = Roll2;
%                     src.UserData.Pitch2(src.UserData.Count2) = Pitch2;
%                     src.UserData.Yaw2(src.UserData.Count2) = Yaw2;
% 
%                     % Update the Count value of the serialport object.
%                     src.UserData.Count2 = src.UserData.Count2 + 1;
% 
%                     elapsedTime = toc;
% 
%                     xdata = get(src.UserData.PlotObj1_5,'XData');
%                     ydata = get(src.UserData.PlotObj1_5,'YData');
%                     set(src.UserData.PlotObj1_5,'XData',[xdata elapsedTime], 'YData',[ydata Roll2],"Color","red");
% 
%                     xdata2 = get(src.UserData.PlotObj2_5,'XData');
%                     ydata2 = get(src.UserData.PlotObj2_5,'YData');
%                     set(src.UserData.PlotObj2_5,'XData',[xdata2 elapsedTime], 'YData',[ydata2 Pitch2],"Color","red");
% 
%                     xdata3 = get(src.UserData.PlotObj3_5,'XData');
%                     ydata3 = get(src.UserData.PlotObj3_5,'YData');
%                     set(src.UserData.PlotObj3_5,'XData',[xdata3 elapsedTime], 'YData',[ydata3 Yaw2],"Color","red");
                end

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %initialization
            app.MainAxes.YLim = [-180, 180];
            app.MainAxes.YTick = [-180, -135, -90, -45, 0, 45, 90, 135, 180];
            app.MainAxes.XLim = [0, 120];
            app.MainAxes.XTick = [0,15,30,45,60,75,90,105,120];
            app.SubLeftAxes.YLim = [-180, 180];
            app.SubLeftAxes.YTick = [-180, -135, -90, -45, 0, 45, 90, 135, 180];
            app.SubLeftAxes.XLim = [0, 120];
            app.SubLeftAxes.XTick = [0,15,30,45,60,75,90,105,120];
            app.SubRightAxes.YLim = [-180, 180];
            app.SubRightAxes.YTick = [-180, -135, -90, -45, 0, 45, 90, 135, 180];
            app.SubRightAxes.XLim = [0, 120];
            app.SubRightAxes.XTick = [0,15,30,45,60,75,90,105,120];

            app.arduinoObj = serialport("COM6", 115200);
            configureTerminator(app.arduinoObj,"CR/LF","CR/LF");
            flush(app.arduinoObj);
            app.arduinoObj.UserData = struct("Roll",[],"Pitch",[],"Yaw",[],"Extra",[],"Count1",1, "Count2",1);
            
            %Pre allocate sizes for speed improvements
            app.arduinoObj.UserData.Roll = zeros(1,1000000); %TODO CALCULATE PROPER SIZE
            app.arduinoObj.UserData.Pitch = zeros(1,1000000); %TODO CALCULATE PROPER SIZE
            app.arduinoObj.UserData.Yaw = zeros(1,1000000); %TODO CALCULATE PROPER SIZE

            %create structs holding all necessary data for an exercise
            app.Exercise1 = struct("Main",[],"Sub1",[],"Sub2",[],"Max",1,"OverComp",'No',"Inc",'No',"time_done",0,"date_done",0);          
        end

        % Button pushed function: StartExerciseButton
        function StartExerciseButtonPushed(app, event)

            if(app.MeasureInProg)
                app.StartExerciseButton.Text = "Start Exercise";
                app.StartExerciseButton.BackgroundColor = 'g';
                app.MeasureInProg = 0;
            else
                app.MeasureInProg = 1;
                app.StartExerciseButton.Text = "Stop Measurement";
                app.StartExerciseButton.BackgroundColor = 'r';
                readline(app.arduinoObj);
                %readline(arduinoObj);
                write(app.arduinoObj,'r',"char");
                tic
                configureCallback(app.arduinoObj,"terminator",@(src, event) readIMUData(app,src,event));
            end
        end

        % Value changed function: SelectanExerciseDropDown
        function SelectanExerciseDropDownValueChanged(app, event)
            
            value = app.SelectanExerciseDropDown.Value;

            app.StartExerciseButton.Enable = 1;
            app.resetmeasurementButton.Enable = 1;
            cleardata(app);

            if(value == "Abduction")
                app.MainPlot = plot(app.MainAxes,NaN,NaN);
                [app.arduinoObj.UserData.RollPlot] = app.MainPlot;
                app.SubPlotLeft = plot(app.SubLeftAxes,NaN,NaN);
                [app.arduinoObj.UserData.PitchPlot] = app.SubPlotLeft;
                app.SubPlotRight = plot(app.SubRightAxes,NaN,NaN);
                [app.arduinoObj.UserData.YawPlot] = app.SubPlotRight;
            elseif(value == "Flexion")
                app.SubPlotLeft = plot(app.SubLeftAxes,NaN,NaN);
                [app.arduinoObj.UserData.RollPlot] = app.SubPlotLeft;
                app.MainPlot = plot(app.MainAxes,NaN,NaN);
                [app.arduinoObj.UserData.PitchPlot] = app.MainPlot;
                app.SubPlotRight = plot(app.SubRightAxes,NaN,NaN);
                [app.arduinoObj.UserData.YawPlot] = app.SubPlotRight;
            elseif(value == "External Rotation")
                app.SubPlotRight = plot(app.SubRightAxes,NaN,NaN);
                [app.arduinoObj.UserData.RollPlot] = app.SubPlotRight;
                app.SubPlotLeft = plot(app.SubLeftAxes,NaN,NaN);
                [app.arduinoObj.UserData.PitchPlot] = app.SubPlotLeft;
                app.MainPlot = plot(app.MainAxes,NaN,NaN);
                [app.arduinoObj.UserData.YawPlot] = app.MainPlot;
            else
                app.StartExerciseButton.Enable = 0;
                app.resetmeasurementButton.Enable = 0;
            end
        end

        % Button pushed function: resetmeasurementButton
        function resetmeasurementButtonPushed(app, event)
            app.StartExerciseButton.Text = "Start Exercise";
            app.StartExerciseButton.BackgroundColor = 'g';
            app.MeasureInProg = 0;
            cleardata(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RoMMeasurementTab = uitab(app.TabGroup);
            app.RoMMeasurementTab.Title = 'RoM Measurement';
            app.MainAxes = uiaxes(app.RoMMeasurementTab);
            title(app.MainAxes, 'Primary Rotation Angle')
            xlabel(app.MainAxes, 'Elapsed Time [s]')
            ylabel(app.MainAxes, 'Displacement Angle')
            zlabel(app.MainAxes, 'Z')
            app.MainAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.MainAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '10'; '0.1'; '0.2'; '0.30000000000000004'; '0.4'; '0.5'; '0.6000000000000001'; '0.7000000000000001'; '0.8'; '0.9'; '1'};
            app.MainAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SubLeftAxes = uiaxes(app.RoMMeasurementTab);
            title(app.SubLeftAxes, 'Auxillary Rotation Angle 1 - Off Center')
            zlabel(app.SubLeftAxes, 'Z')
            app.SubRightAxes = uiaxes(app.RoMMeasurementTab);
            title(app.SubRightAxes, 'Auxillary Rotation Angle 2 - Twist')
            app.SelectanExerciseDropDown = uidropdown(app.RoMMeasurementTab);
            app.SelectanExerciseDropDown.Items = {'Select An Exercise...', 'Flexion', 'Abduction', 'External Rotation', 'Other'};
            app.SelectanExerciseDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanExerciseDropDownValueChanged, true);
            app.SelectanExerciseDropDown.Value = 'Select An Exercise...';
            app.Image = uiimage(app.RoMMeasurementTab);
            app.Image.ImageSource = 'CromaTech Logo.png';
            app.resetmeasurementButton = uibutton(app.RoMMeasurementTab, 'push');
            app.resetmeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @resetmeasurementButtonPushed, true);
            app.resetmeasurementButton.Enable = 'off';
            app.resetmeasurementButton.Text = 'reset measurement';
            app.StartExerciseButton = uibutton(app.RoMMeasurementTab, 'push');
            app.StartExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @StartExerciseButtonPushed, true);
            app.StartExerciseButton.Enable = 'off';
            app.StartExerciseButton.Text = 'Start Exercise';
            app.CurrentAngleEditFieldLabel = uilabel(app.RoMMeasurementTab);
            app.CurrentAngleEditFieldLabel.Text = 'Current Angle';
            app.CurrentAngleEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.curr_angle = uilabel(app.RoMMeasurementTab);
            app.curr_angle.Text = '0';
            app.MaxAngleEditFieldLabel = uilabel(app.RoMMeasurementTab);
            app.MaxAngleEditFieldLabel.Text = 'Max Angle';
            app.MaxAngleEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.max_angle = uilabel(app.RoMMeasurementTab);
            app.max_angle.Text = '0';
            app.OvercompensationDetectedLabel = uilabel(app.RoMMeasurementTab);
            app.OvercompensationDetectedLabel.Text = {'Overcompensation'; 'Detected'};
            app.OvercompensationDetectedEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.NOLabel = uilabel(app.RoMMeasurementTab);
            app.NOLabel.Text = 'NO';
            app.IncorrectFormDetectedEditFieldLabel = uilabel(app.RoMMeasurementTab);
            app.IncorrectFormDetectedEditFieldLabel.Text = {'Incorrect Form '; 'Detected'};
            app.IncorrectFormDetectedEditField = uieditfield(app.RoMMeasurementTab, 'text');
            app.NOLabel_2 = uilabel(app.RoMMeasurementTab);
            app.NOLabel_2.Text = 'NO';
            app.CalibratingLabel = uilabel(app.RoMMeasurementTab);
            app.CalibratingLabel.Visible = 'off';
            app.CalibratingLabel.Text = {'Calibrating...'; ''};
            app.ViewPreviousExercisesTab = uitab(app.TabGroup);
            app.ViewPreviousExercisesTab.Title = 'View Previous Exercises';
            app.UIAxes = uiaxes(app.ViewPreviousExercisesTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.ViewPreviousExercisesTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.ViewPreviousExercisesTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.AbductionListBoxLabel = uilabel(app.ViewPreviousExercisesTab);
            app.AbductionListBoxLabel.Text = 'Abduction';
            app.AbductionListBox = uilistbox(app.ViewPreviousExercisesTab);
            app.AbductionListBox.Items = {'No Previous Measurements', ''};
            app.AbductionListBox.Value = 'No Previous Measurements';
            app.FlexionListBoxLabel = uilabel(app.ViewPreviousExercisesTab);
            app.FlexionListBoxLabel.Text = 'Flexion';
            app.FlexionListBox = uilistbox(app.ViewPreviousExercisesTab);
            app.FlexionListBox.Items = {'No Previous Measurements'};
            app.FlexionListBox.Value = 'No Previous Measurements';
            app.ExternalRotationListBoxLabel = uilabel(app.ViewPreviousExercisesTab);
            app.ExternalRotationListBoxLabel.Text = {'External'; 'Rotation'};
            app.ExternalRotationListBox = uilistbox(app.ViewPreviousExercisesTab);
            app.ExternalRotationListBox.Items = {'03/19/23 - 11:51', '03/25/23 - 14:28'};
            app.ExternalRotationListBox.Value = '03/19/23 - 11:51';
            app.Image_2 = uiimage(app.ViewPreviousExercisesTab);
            app.Image_2.ImageSource = 'CromaTech Logo.png';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TESTAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end